classdef uipt < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        GridLayout5                   matlab.ui.container.GridLayout
        TabGroup                      matlab.ui.container.TabGroup
        MallaTab                      matlab.ui.container.Tab
        GridLayout3                   matlab.ui.container.GridLayout
        GridLayout10                  matlab.ui.container.GridLayout
        HmaxSpinnerLabel              matlab.ui.control.Label
        HmaxSpinner                   matlab.ui.control.Spinner
        NodesCheckBox                 matlab.ui.control.CheckBox
        EdgesCheckBox                 matlab.ui.control.CheckBox
        ElementsCheckBox              matlab.ui.control.CheckBox
        UIAxes3                       matlab.ui.control.UIAxes
        ResultadosTab                 matlab.ui.container.Tab
        GridLayout4                   matlab.ui.container.GridLayout
        UIAxes4                       matlab.ui.control.UIAxes
        GridLayout12                  matlab.ui.container.GridLayout
        ResultadosDropDownLabel       matlab.ui.control.Label
        ResultadosDropDown            matlab.ui.control.DropDown
        GeometriaTab_2                matlab.ui.container.Tab
        GridLayout13                  matlab.ui.container.GridLayout
        Image                         matlab.ui.control.Image
        GridLayout6                   matlab.ui.container.GridLayout
        ResolverButton                matlab.ui.control.Button
        WorkspaceButton               matlab.ui.control.Button
        SalirButton                   matlab.ui.control.Button
        TabGroup2                     matlab.ui.container.TabGroup
        DatosTab                      matlab.ui.container.Tab
        GridLayout7                   matlab.ui.container.GridLayout
        GeometriaPanel                matlab.ui.container.Panel
        GridLayout8                   matlab.ui.container.GridLayout
        FormaDropDownLabel            matlab.ui.control.Label
        FormaDropDown                 matlab.ui.control.DropDown
        UITable                       matlab.ui.control.Table
        MaterialPanel                 matlab.ui.container.Panel
        GridLayout9                   matlab.ui.container.GridLayout
        ModulodeYoungEEditFieldLabel  matlab.ui.control.Label
        ModulodeYoungEEditField       matlab.ui.control.NumericEditField
        CoefPoissonvEditFieldLabel    matlab.ui.control.Label
        CoefPoissonvEditField         matlab.ui.control.NumericEditField
        RestriccionesPanel            matlab.ui.container.Panel
        GridLayout11                  matlab.ui.container.GridLayout
        CargaPEditFieldLabel          matlab.ui.control.Label
        CargaPEditField               matlab.ui.control.NumericEditField
        InfoTab                       matlab.ui.container.Tab
    end

    
    properties (Access = private)
        Results;
        geom;
        fgeom=1;
        mmesh;
        material;
        load;
        res;
    end
    
    methods (Access = private)


        function plotTriMesh(app)
            
            app.UIAxes3.cla;
            flag = [app.NodesCheckBox.Value, ...
                    app.EdgesCheckBox.Value, ...
                    app.ElementsCheckBox.Value];

            app.mmesh = gtrimesh(app.geom,app.HmaxSpinner.Value);
            plotrimesh(app.UIAxes3, app.mmesh.Mesh, flag);
            
        end
         
        function updateGeom(app)
            
            data = app.UITable.Data.Valor';
            
            switch app.fgeom
                case 1
                    app.geom = holeplate(data(1),data(2),data(3),data(4),data(5));
                    app.Image.ImageSource = 'holeplate.png';
                case 2
                    app.geom = dholeplate(data(1),data(2),data(3),data(4),data(5),data(6),data(7));
                    app.Image.ImageSource = 'dholeplate.png';
                case 3
                    app.geom = tplate(data(1),data(2),data(3),data(4),data(5));
                    app.Image.ImageSource = 'tplate.png';
                case 4
                    app.geom = rhplate(data(1),data(2),data(3));
                    app.Image.ImageSource = 'rhplate.png';
            end
%             
        end
        
        
        function updateProps(app)
            app.material = [app.ModulodeYoungEEditField.Value, ...
                app.CoefPoissonvEditField.Value];
            app.load = app.CargaPEditField.Value;
        end
        
        function resetCase(app)
            app.mmesh = [];
        end
        
        function plotResults(app)
            
            app.UIAxes4.cla;
            
            nx = app.mmesh.Mesh.Nodes(1,:)';
            ny = app.mmesh.Mesh.Nodes(2,:)';
            
            switch str2double(app.ResultadosDropDown.Value)
                case 1
                    r = app.res.Displacement.x;
                case 2
                    r = app.res.Displacement.y;
                case 3
                    r = app.res.Strain.xx;
                case 4
                    r = app.res.Strain.yy;
                case 5
                    r = app.res.Strain.xy;
                case 6
                    r = app.res.Stress.xx;
                case 7
                    r = app.res.Stress.yy;
                case 8
                    r = app.res.Stress.xy;
            end
            
            scatter(app.UIAxes4,nx,ny,30,r,'filled');
            axis(app.UIAxes4, 'equal');
            colorbar(app.UIAxes4);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SalirButton
        function SalirButtonPushed(app, event)
            app.delete;
        end

        % Button pushed function: ResolverButton
        function ResolverButtonPushed(app, event)
            app.resetCase;
            app.updateGeom;        
            app.plotTriMesh;
            app.updateProps;
            app.res = mapped(app.mmesh,app.material,app.load,app.fgeom);
%           disp(app.mmesh);
            h = app.TabGroup.Children(2);
            app.TabGroup.SelectedTab = h;
            app.plotResults;
        end

        % Value changed function: FormaDropDown
        function FormaDropDownValueChanged(app, event)
            value = str2double(app.FormaDropDown.Value);
            app.fgeom = value;
            
            switch value
                case 1
%                     disp('HolePlate');
                    props = {'Ancho (W) [mm]', 'Largo (L) [mm]', ...
                             'PosX circulo (cx) [mm]', ...
                             'PosY circulo (cy) [mm]', ...
                             'Radio (r) [mm]'};
                                      
                    sz = categorical([1; 2; 3; 4; 5],1:5,props);
                    num = [40; 200; 100; 20; 10];
                    tdata = table(sz,num,'VariableNames',{'Dimension','Valor'});
                    app.UITable.Data = tdata;
                                    
                    
                case 2
%                     disp('DHolePlate');
                    props = {'Ancho (W) [mm]', 'Largo (L) [mm]', ...
                             'PosX circulo 1 (cx1) [mm]', ...
                             'Radio circulo 1 (r1) [mm]', ...
                             'PosX circulo 2 (cx2) [mm]', ...
                             'Radio circulo 2 (r2) [mm]', ...
                             'Distancia (d) [mm]'};

                    sz = categorical([1;2;3;4;5;6;7],1:7,props);
                    num = [40; 200;100;20;100;10;20];
                    tdata = table(sz,num,'VariableNames',{'Dimension','Valor'});
                    app.UITable.Data = tdata;
                    
                    
                case 3
%                     disp('TPlate');
                    props = {'Ancho (W) [mm]', 'Largo (L) [mm]', ...
                             'PosX circulos (cx) [mm]', ...
                             'Radio circulos (r) [mm]', ...
                             'Distancia (d) [mm]'};
                    
                    sz = categorical([1;2;3;4;5],1:5,props);
                    num = [40; 200;100;20;20];
                    tdata = table(sz,num,'VariableNames',{'Dimension','Valor'});
                    app.UITable.Data = tdata;
                    
                    
                case 4
%                     disp('RingPlate');
                    props = {'Ancho (W) [mm]', 'Largo (L) [mm]', ...
                             'Radio circulo (r) [mm]'};
                         
                    sz = categorical([1;2;3],1:3,props);
                    num = [40; 200;10];
                    tdata = table(sz,num,'VariableNames',{'Dimension','Valor'});
                    app.UITable.Data = tdata;     
            end
            
            app.updateGeom();
            app.plotTriMesh();
            app.updateProps();
            
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            app.updateGeom();        
            app.plotTriMesh();
            app.updateProps();
        end

        % Value changed function: HmaxSpinner
        function HmaxSpinnerValueChanged(app, event)
            app.updateGeom();        
            app.plotTriMesh();
            app.updateProps();
        end

        % Value changed function: NodesCheckBox
        function NodesCheckBoxValueChanged(app, event)
            app.updateGeom();        
            app.plotTriMesh();
            app.updateProps();
        end

        % Value changed function: EdgesCheckBox
        function EdgesCheckBoxValueChanged(app, event)
            app.updateGeom();        
            app.plotTriMesh();
        end

        % Value changed function: ElementsCheckBox
        function ElementsCheckBoxValueChanged(app, event)
            app.updateGeom();        
            app.plotTriMesh();
            app.updateProps();
        end

        % Value changed function: ResultadosDropDown
        function ResultadosDropDownValueChanged(app, event)
            app.plotResults;
        end

        % Button pushed function: WorkspaceButton
        function WorkspaceButtonPushed(app, event)
            results.ux = app.res.Displacement.x;
            results.uy = app.res.Displacement.y;
            results.strxx = app.res.Strain.xx;
            results.stryy = app.res.Strain.yy;
            results.strxy = app.res.Strain.xy;
            results.stsxx = app.res.Stress.xx;
            results.stsyy = app.res.Stress.yy;
            results.stsxy = app.res.Stress.xy;
            results.nodes = app.mmesh.Mesh.Nodes';
            results.elements = app.mmesh.Mesh.Elements';
            assignin("base", "results", results);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.TabGroup = uitabgroup(app.GridLayout5);
            app.MallaTab = uitab(app.TabGroup);
            app.MallaTab.Title = 'Malla';
            app.GridLayout3 = uigridlayout(app.MallaTab);
            app.GridLayout10 = uigridlayout(app.GridLayout3);
            app.HmaxSpinnerLabel = uilabel(app.GridLayout10);
            app.HmaxSpinnerLabel.Text = 'Hmax';
            app.HmaxSpinner = uispinner(app.GridLayout10);
            app.HmaxSpinner.Limits = [1 10];
            app.HmaxSpinner.ValueChangedFcn = createCallbackFcn(app, @HmaxSpinnerValueChanged, true);
            app.HmaxSpinner.Value = 5;
            app.NodesCheckBox = uicheckbox(app.GridLayout10);
            app.NodesCheckBox.ValueChangedFcn = createCallbackFcn(app, @NodesCheckBoxValueChanged, true);
            app.NodesCheckBox.Text = 'Nodes';
            app.EdgesCheckBox = uicheckbox(app.GridLayout10);
            app.EdgesCheckBox.ValueChangedFcn = createCallbackFcn(app, @EdgesCheckBoxValueChanged, true);
            app.EdgesCheckBox.Text = 'Edges';
            app.EdgesCheckBox.Value = true;
            app.ElementsCheckBox = uicheckbox(app.GridLayout10);
            app.ElementsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ElementsCheckBoxValueChanged, true);
            app.ElementsCheckBox.Text = 'Elements';
            app.ElementsCheckBox.Value = true;
            app.UIAxes3 = uiaxes(app.GridLayout3);
            title(app.UIAxes3, '')
            xlabel(app.UIAxes3, '')
            ylabel(app.UIAxes3, '')
            app.ResultadosTab = uitab(app.TabGroup);
            app.ResultadosTab.Title = 'Resultados';
            app.GridLayout4 = uigridlayout(app.ResultadosTab);
            app.UIAxes4 = uiaxes(app.GridLayout4);
            title(app.UIAxes4, '')
            xlabel(app.UIAxes4, '')
            ylabel(app.UIAxes4, '')
            app.GridLayout12 = uigridlayout(app.GridLayout4);
            app.ResultadosDropDownLabel = uilabel(app.GridLayout12);
            app.ResultadosDropDownLabel.Text = 'Resultados';
            app.ResultadosDropDown = uidropdown(app.GridLayout12);
            app.ResultadosDropDown.Items = {'ux', 'uy', 'strxx', 'stryy', 'strxy', 'stsxx', 'stsyy', 'stsxy'};
            app.ResultadosDropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.ResultadosDropDown.ValueChangedFcn = createCallbackFcn(app, @ResultadosDropDownValueChanged, true);
            app.ResultadosDropDown.Value = '1';
            app.GeometriaTab_2 = uitab(app.TabGroup);
            app.GeometriaTab_2.Title = 'Geometria';
            app.GridLayout13 = uigridlayout(app.GeometriaTab_2);
            app.Image = uiimage(app.GridLayout13);
            app.Image.ImageSource = 'holeplate.png';
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.ResolverButton = uibutton(app.GridLayout6, 'push');
            app.ResolverButton.ButtonPushedFcn = createCallbackFcn(app, @ResolverButtonPushed, true);
            app.ResolverButton.Text = 'Resolver';
            app.WorkspaceButton = uibutton(app.GridLayout6, 'push');
            app.WorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @WorkspaceButtonPushed, true);
            app.WorkspaceButton.Text = 'Workspace';
            app.SalirButton = uibutton(app.GridLayout6, 'push');
            app.SalirButton.ButtonPushedFcn = createCallbackFcn(app, @SalirButtonPushed, true);
            app.SalirButton.Text = 'Salir';
            app.TabGroup2 = uitabgroup(app.GridLayout);
            app.DatosTab = uitab(app.TabGroup2);
            app.DatosTab.Title = 'Datos';
            app.GridLayout7 = uigridlayout(app.DatosTab);
            app.GeometriaPanel = uipanel(app.GridLayout7);
            app.GeometriaPanel.Title = 'Geometria';
            app.GridLayout8 = uigridlayout(app.GeometriaPanel);
            app.FormaDropDownLabel = uilabel(app.GridLayout8);
            app.FormaDropDownLabel.Text = 'Forma';
            app.FormaDropDown = uidropdown(app.GridLayout8);
            app.FormaDropDown.Items = {'Hole Plate', 'Double Hole Plate', 'T Plate', 'Ring Plate'};
            app.FormaDropDown.ItemsData = {'1', '2', '3', '4'};
            app.FormaDropDown.ValueChangedFcn = createCallbackFcn(app, @FormaDropDownValueChanged, true);
            app.FormaDropDown.Tag = 'formSelection';
            app.FormaDropDown.Value = '1';
            app.UITable = uitable(app.GridLayout8);
            app.UITable.ColumnName = {'Dimension'; 'Valor'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.MaterialPanel = uipanel(app.GridLayout7);
            app.MaterialPanel.Title = 'Material';
            app.GridLayout9 = uigridlayout(app.MaterialPanel);
            app.ModulodeYoungEEditFieldLabel = uilabel(app.GridLayout9);
            app.ModulodeYoungEEditFieldLabel.Text = 'Modulo de Young (E)';
            app.ModulodeYoungEEditField = uieditfield(app.GridLayout9, 'numeric');
            app.ModulodeYoungEEditField.Tooltip = {'[Pa]'};
            app.ModulodeYoungEEditField.Value = 210000;
            app.CoefPoissonvEditFieldLabel = uilabel(app.GridLayout9);
            app.CoefPoissonvEditFieldLabel.Text = 'Coef. Poisson (v)';
            app.CoefPoissonvEditField = uieditfield(app.GridLayout9, 'numeric');
            app.CoefPoissonvEditField.Value = 0.25;
            app.RestriccionesPanel = uipanel(app.GridLayout7);
            app.RestriccionesPanel.Title = 'Restricciones';
            app.GridLayout11 = uigridlayout(app.RestriccionesPanel);
            app.CargaPEditFieldLabel = uilabel(app.GridLayout11);
            app.CargaPEditFieldLabel.Text = 'Carga (P)';
            app.CargaPEditField = uieditfield(app.GridLayout11, 'numeric');
            app.CargaPEditField.Tooltip = {'[Pa]'};
            app.CargaPEditField.Value = 100;
            app.InfoTab = uitab(app.TabGroup2);
            app.InfoTab.Title = 'Info';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = uipt

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end